classdef solver_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RunButton                    matlab.ui.control.Button
        TerminaltimeEditFieldLabel   matlab.ui.control.Label
        TerminaltimeEditField        matlab.ui.control.NumericEditField
        TerminalstateEditFieldLabel  matlab.ui.control.Label
        TerminalstateEditField       matlab.ui.control.EditField
        InitialstateEditFieldLabel   matlab.ui.control.Label
        InitialstateEditField        matlab.ui.control.EditField
        BasisButtonGroup             matlab.ui.container.ButtonGroup
        ChebyshevButton              matlab.ui.control.RadioButton
        LegendreButton               matlab.ui.control.RadioButton
        FourierButton                matlab.ui.control.RadioButton
        basisEditFieldLabel          matlab.ui.control.Label
        basisEditField               matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel      matlab.ui.control.Label
        ToleranceEditField           matlab.ui.control.NumericEditField
        IterationEditFieldLabel      matlab.ui.control.Label
        IterationEditField           matlab.ui.control.NumericEditField
        alphaSpinnerLabel            matlab.ui.control.Label
        alphaSpinner                 matlab.ui.control.Spinner
        InitialtimeEditFieldLabel    matlab.ui.control.Label
        InitialtimeEditField         matlab.ui.control.NumericEditField
        umaxEditFieldLabel           matlab.ui.control.Label
        umaxEditField                matlab.ui.control.EditField
        uminEditFieldLabel           matlab.ui.control.Label
        uminEditField                matlab.ui.control.EditField
        rhoEditFieldLabel            matlab.ui.control.Label
        rhoEditField                 matlab.ui.control.NumericEditField
        uEditFieldLabel              matlab.ui.control.Label
        uEditField                   matlab.ui.control.NumericEditField
        RightPanel                   matlab.ui.container.Panel
        JEditFieldLabel              matlab.ui.control.Label
        JEditField                   matlab.ui.control.NumericEditField
        ConvergenceEditFieldLabel    matlab.ui.control.Label
        ConvergenceEditField         matlab.ui.control.EditField
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            order = app.basisEditField.Value;
            t0 = app.InitialtimeEditField.Value;
            tf = app.TerminaltimeEditField.Value;
            dt = 0.01;
            tol = app.ToleranceEditField.Value;
            nu = app.uEditField.Value;
            total_step = (tf-t0)/dt;
            u = zeros(nu, total_step);
            x0 = str2num(app.InitialstateEditField.Value);
            x0 = x0';
            xf = str2num(app.TerminalstateEditField.Value);
            xf = xf';
            nx = length(x0);
            
            if length(xf)~=0
               lambda = rand(1, length(xf)); % Lagrangian multiplier
            end
            
            x = zeros(nx,total_step);
            x(:,1)= x0;
            chosen_Basis = app.BasisButtonGroup.SelectedObject.Text;
            if strcmp(chosen_Basis, 'Chebyshev') || strcmp(chosen_Basis, 'Legendre')
                theta = rand((order+1)*nu, 1);
            else
                theta = rand((2*order+1)*nu, 1);
            end
            N = app.IterationEditField.Value;
            J0 = inf;
            alpha = app.alphaSpinner.Value;
            umin = str2num(app.uminEditField.Value);
            umax = str2num(app.umaxEditField.Value);
            rho = app.rhoEditField.Value;
            cost_all = [];
            for iter=1:N
                x = sysdynamic(x0,u,dt);
                J = cost(x,u,dt);
                if length(xf)~=0
                    J=J+lambda*(x(:,end)-xf)+rho*sum((x(:,end)-xf).^2);
                end
                cost_all = [cost_all;J];
                dJdtheta = GE(theta, lambda, rho, dt, tf, x0,xf,chosen_Basis,order, umin, umax);
                thetak = theta-alpha*dJdtheta(1:length(theta));
                lambdak = lambda+alpha*dJdtheta(length(theta)+1:end);
                uk = rebuiltU(theta, dt, tf, chosen_Basis, order, umin, umax);
                app.ConvergenceEditField.Value = string(iter);
                if abs(J-J0) < tol && abs(norm(theta)-norm(thetak))<tol && abs(norm(lambdak)-norm(lambda))<tol
                    app.ConvergenceEditField.Value = 'Converge!!!';
                    break;
                else
                    app.ConvergenceEditField.Value = string(iter);
                    u = uk;
                    theta = thetak;
                    J0 = J;
                end
               plot(app.UIAxes2_2, cost_all);
               drawnow;
            end
            app.JEditField.Value = J;
            t = (1:total_step)*dt;
            plot(app.UIAxes,t,u);
            plot(app.UIAxes2,t,x);
            
            
            function dJdp = GE(theta, lambda, rho, dt,T,x0, xf, chosen_basis, order, umin,umax)
                params = [theta;lambda'];
                m = length(params);
                delta = 0.01;
                n = m+20;
                dp = delta.*randn(m,n);
                dJ = zeros(n,1);
                l_theta = length(theta);
                for i=1:n
                    cost1 = perturbdJ(params-dp(:,i),rho, l_theta, dt,T,x0,xf,chosen_basis, order, umin,umax);   % J(theta-dtheta)
                    cost2 = perturbdJ(params+dp(:,i),rho, l_theta, dt,T,x0,xf,chosen_basis, order, umin,umax);   % J(theta+dtheta) 
                    dJ(i)= cost2-cost1;
                end
                dJdp = (pinv(dp))'*dJ;
           end
           
            function newJ = perturbdJ(params,rho, l_theta, dt,tf,x0, xf, chosen_basis, order, umin,umax)
                theta = params(1:l_theta);
                lambda = params(l_theta+1:end);lambda=lambda';
                u = rebuiltU(theta, dt, tf, chosen_basis, order, umin, umax);
                x = sysdynamic(x0, u, dt);
                newJ = cost(x, u, dt)+lambda*xf+rho*sum((x(:,end)-xf).^2);
            end

            function u = rebuiltU(theta, dt, tf, chosen_basis, order, umin, umax)
                if strcmp(chosen_basis, 'Chebyshev')
                    shapefcn = polyBasis('chebyshev', order);
                elseif strcmp(chosen_basis, 'Legendre')
                    shapefcn = @(x) fastLegendre(order, x);
                else
                    shapefcn = fourierBasis(order);
                end
                nu = length(umin);
                theta = reshape(theta, [int64(length(theta)/nu),nu]);
                for j=1:tf/dt
                    curr_time = j*dt;
                    if tf <= 1
                        tau = curr_time;
                    else
                        tau = 2*curr_time/(tf)-1;
                    end
                     ut = clip([1, shapefcn(tau)]*theta, umin, umax);
                     u(:,j)= ut';
                end
            end
            
            function u = clip(u,umin,umax)
                u=min(max(u,umin),umax);
            end
            
            function y = fastLegendre(m,x)
                if m==1
                    y = x;
                else
                    y = zeros(1,m);
                    y(1) = x;
                    y(2) = 1/2*(3*x^2-1);
                    for ct=2:m-1
                        y(ct+1) = (2*ct-1)/ct*x*y(ct)-(ct-1)/ct*y(ct-1);
                    end
                end
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {205, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TerminaltimeEditFieldLabel = uilabel(app.LeftPanel);
            app.TerminaltimeEditFieldLabel.Text = 'Terminal time';
            app.TerminaltimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TerminaltimeEditField.Limits = [0 Inf];
            app.TerminaltimeEditField.Value = 2;
            app.TerminalstateEditFieldLabel = uilabel(app.LeftPanel);
            app.TerminalstateEditFieldLabel.Text = 'Terminal state';
            app.TerminalstateEditField = uieditfield(app.LeftPanel, 'text');
            app.TerminalstateEditField.Value = '[0,0]';
            app.InitialstateEditFieldLabel = uilabel(app.LeftPanel);
            app.InitialstateEditFieldLabel.Text = 'Initial state';
            app.InitialstateEditField = uieditfield(app.LeftPanel, 'text');
            app.InitialstateEditField.Value = '[-8,-6]';
            app.BasisButtonGroup = uibuttongroup(app.LeftPanel);
            app.BasisButtonGroup.Title = 'Basis';
            app.ChebyshevButton = uiradiobutton(app.BasisButtonGroup);
            app.ChebyshevButton.Text = 'Chebyshev';
            app.ChebyshevButton.Value = true;
            app.LegendreButton = uiradiobutton(app.BasisButtonGroup);
            app.LegendreButton.Text = 'Legendre';
            app.FourierButton = uiradiobutton(app.BasisButtonGroup);
            app.FourierButton.Text = 'Fourier';
            app.basisEditFieldLabel = uilabel(app.LeftPanel);
            app.basisEditFieldLabel.Text = '#basis';
            app.basisEditField = uieditfield(app.LeftPanel, 'numeric');
            app.basisEditField.Limits = [0 Inf];
            app.basisEditField.Value = 6;
            app.ToleranceEditFieldLabel = uilabel(app.LeftPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ToleranceEditField.Limits = [0 Inf];
            app.ToleranceEditField.Value = 0.01;
            app.IterationEditFieldLabel = uilabel(app.LeftPanel);
            app.IterationEditFieldLabel.Text = '#Iteration';
            app.IterationEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IterationEditField.Limits = [0 Inf];
            app.IterationEditField.Value = 1000;
            app.alphaSpinnerLabel = uilabel(app.LeftPanel);
            app.alphaSpinnerLabel.Text = 'alpha';
            app.alphaSpinner = uispinner(app.LeftPanel);
            app.alphaSpinner.Step = 0.005;
            app.alphaSpinner.Limits = [0.001 0.1];
            app.alphaSpinner.Value = 0.001;
            app.InitialtimeEditFieldLabel = uilabel(app.LeftPanel);
            app.InitialtimeEditFieldLabel.Text = 'Initial time';
            app.InitialtimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.InitialtimeEditField.Limits = [0 Inf];
            app.umaxEditFieldLabel = uilabel(app.LeftPanel);
            app.umaxEditFieldLabel.Text = 'umax';
            app.umaxEditField = uieditfield(app.LeftPanel, 'text');
            app.umaxEditField.Value = '[10]';
            app.uminEditFieldLabel = uilabel(app.LeftPanel);
            app.uminEditFieldLabel.Text = 'umin';
            app.uminEditField = uieditfield(app.LeftPanel, 'text');
            app.uminEditField.Value = '[-10]';
            app.rhoEditFieldLabel = uilabel(app.LeftPanel);
            app.rhoEditFieldLabel.Text = 'rho';
            app.rhoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.uEditFieldLabel = uilabel(app.LeftPanel);
            app.uEditFieldLabel.Text = '#u';
            app.uEditField = uieditfield(app.LeftPanel, 'numeric');
            app.uEditField.Limits = [0 Inf];
            app.uEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.JEditFieldLabel = uilabel(app.RightPanel);
            app.JEditFieldLabel.Text = 'J=';
            app.JEditField = uieditfield(app.RightPanel, 'numeric');
            app.ConvergenceEditFieldLabel = uilabel(app.RightPanel);
            app.ConvergenceEditFieldLabel.Text = 'Convergence';
            app.ConvergenceEditField = uieditfield(app.RightPanel, 'text');
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Controller with respect to time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'u')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'States with respect to time')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'x')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            title(app.UIAxes2_2, 'Optimal cost with respect to iteration number')
            xlabel(app.UIAxes2_2, 'Iteration')
            ylabel(app.UIAxes2_2, 'J')
            zlabel(app.UIAxes2_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solver_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end